classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WelcomeAdminLabel         matlab.ui.control.Label
        InsertDatasetPanel        matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        ChooseFolderButton        matlab.ui.control.Button
        RunSVMButton              matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        SelectSegmentationAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        HammoucheAlgorithmButton  matlab.ui.control.RadioButton
        DragonflyAlgorithmButton  matlab.ui.control.RadioButton
        SelectFeatureExtractionButtonGroup  matlab.ui.container.ButtonGroup
        GLCMButton                matlab.ui.control.RadioButton
        LESHButton                matlab.ui.control.RadioButton
        Label_2                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        folder % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            app.folder = uigetdir('C:\');
            % app.folder = uigetdir('C:\Users\New Hp\Documents\MATLAB\fit3162\Code');
            
            app.Label_2.Text=app.folder;
            app.Label_2.Text
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
            x=6;
            y=2;
            val=0;
            seg=app.SelectSegmentationAlgorithmButtonGroup.SelectedObject.Text;
            fea=app.SelectFeatureExtractionButtonGroup.SelectedObject.Text;
            
            %note that currently all 4 func output into diff excel sheet
            % conf matric, i think you can hook it up urself
            
            % Hammouche
            if seg=="Hammouche Algorithm"
                if fea=="GLCM"
                    val=add(6,2);
                    % hammoucheGLCM(app.folder)
                    % con_matric=SVM_GLCM("results_extra_hammouche_glcm_3class.xlsx")
                else
                    % hammoucheLESH(app.folder)
                    % con_matric=SVM_LESH("results_extra_hammouche_lesh_3class.xlsx")
                    val=minusnum(6,2);
                end
                
            % Region Growing
            else
                if fea=="LESH"
                    % regionGrowingLESH(app.folder)
                    % con_matric=SVM_LESH("results_extra_rgrowing_lesh_3class.xlsx")
                    val=multiplynum(6,2);
                else
                    % regionGrowingGLCM(app.folder)
                    % con_matric=SVM_GLCM("results_extra_rgrowing_glcm_3class.xlsx")
                    val=divnum(6,2);
                end
            end
            val
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeAdminLabel = uilabel(app.UIFigure);
            app.WelcomeAdminLabel.Text = 'Welcome Admin';
            app.InsertDatasetPanel = uipanel(app.UIFigure);
            app.InsertDatasetPanel.Title = 'Insert Dataset';
            app.Label = uilabel(app.InsertDatasetPanel);
            app.Label.Text = '';
            app.ChooseFolderButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.RunSVMButton = uibutton(app.UIFigure, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SelectSegmentationAlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectSegmentationAlgorithmButtonGroup.Title = 'Select Segmentation Algorithm';
            app.HammoucheAlgorithmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.HammoucheAlgorithmButton.Text = 'Hammouche Algorithm';
            app.HammoucheAlgorithmButton.Value = true;
            app.DragonflyAlgorithmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.DragonflyAlgorithmButton.Text = 'Dragonfly Algorithm';
            app.SelectFeatureExtractionButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectFeatureExtractionButtonGroup.Title = 'Select Feature Extraction';
            app.GLCMButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.GLCMButton.Text = 'GLCM';
            app.GLCMButton.Value = true;
            app.LESHButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.LESHButton.Text = 'LESH';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end